classdef app2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangeDetection                 matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        Saveresultsas                   matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        StrategySelection               matlab.ui.container.ButtonGroup
        smallData                       matlab.ui.control.RadioButton
        BigData                         matlab.ui.control.RadioButton
        Saveintermediatefiles           matlab.ui.container.ButtonGroup
        NoButton_save                   matlab.ui.control.RadioButton
        YesButton_save                  matlab.ui.control.RadioButton
        EntertheThresholdepslnEditFieldLabel  matlab.ui.control.Label
        EnterThresholdepsln             matlab.ui.control.NumericEditField
        InitialChangeMaskButton         matlab.ui.container.ButtonGroup
        NomaskButton                    matlab.ui.control.RadioButton
        strictrelaxed                   matlab.ui.container.ButtonGroup
        StrictButton                    matlab.ui.control.RadioButton
        RelaxedButton                   matlab.ui.control.RadioButton
        UsingPrincipalComponents        matlab.ui.control.RadioButton
        UsingOriginalDataSet            matlab.ui.control.RadioButton
        ICMvaluemask                    matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        EnterthepercentegeEditFieldLabel  matlab.ui.control.Label
        Enterthepercentege              matlab.ui.control.NumericEditField
        MADChangeLabel                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        InputData                       matlab.ui.control.Button
        BandNamefortime1EditFieldLabel  matlab.ui.control.Label
        BandNamefortime1                matlab.ui.control.EditField
        BandNamefortime2EditFieldLabel  matlab.ui.control.Label
        BandNamefortime2                matlab.ui.control.EditField
    end


    properties (Access = private)
       opts = zeros(5,1); % Default
       image_t1;
       image_t2;
       band_name_t1;
       band_name_t2;
       save_name;
       path_name;
       rho
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = gcp('nocreate');
            if isempty(p)
                parpool;
            end
        end

        % Selection changed function: StrategySelection
        function Strategy(app, event)
            switch app.StrategySelection.SelectedObject
                case app.smallData
                    app.opts(2) = 0;
                case app.BigData
                    app.opts(2) = 1;
            end
        end

        % Selection changed function: ICMvaluemask
        function ICMvalueMask(app, event)
            switch app.ICMvaluemask.SelectedObject
                case app.YesButton
                    app.Enterthepercentege.Visible = 'on';
                case app.NoButton
                    app.Enterthepercentege.Visible = 'off'; 
            end
            
        end

        % Selection changed function: InitialChangeMaskButton
        function InitialChangeMask(app, event)
            switch app.InitialChangeMaskButton.SelectedObject
                case app.NomaskButton
                    app.strictrelaxed.Visible = 'off';
                    app.ICMvaluemask.Visible = 'off';
                    app.opts(3) = 0;
                case app.UsingPrincipalComponents
                    app.ICMvaluemask.Visible = 'on';
                    app.strictrelaxed.Visible = 'off';
                    app.opts(3) = 3;
                case app.UsingOriginalDataSet
                    app.ICMvaluemask.Visible = 'on';
                    app.strictrelaxed.Visible = 'on';
            end
        end

        % Button pushed function: InputData
        function InputDataPushed(app, event)
            app.image_t1 = uipickfiles('Promp','Select images at t1');
            if isequal(app.image_t1,0)
                disp('exit from changeDetection function');
                return;
            end
            app.image_t2 = uipickfiles('Promp','Select images at t2');
            if isequal(app.image_t2,0)
                disp('exit from changeDetection function');
                return;
            end
        end

        % Button pushed function: RunButton
        function Run(app, event)
             app.opts(1) = app.EnterThresholdepsln.Value;
             app.opts(4) = app.Enterthepercentege.Value;
             % band names
             tmp = textscan(app.BandNamefortime1.Value,'%s','Delimiter',',')';
             app.band_name_t1 = tmp{:}';
             tmp = textscan(app.BandNamefortime2.Value,'%s','Delimiter',',')';
             app.band_name_t2 = tmp{:}';
             % Main function run
             MainIRMAD(app.image_t1,app.image_t2,app.band_name_t1,app.band_name_t2,app.opts,app.save_name,app.path_name);
        end

        % Button pushed function: Saveresultsas
        function SaveResultsAs(app, event)
            % save_name and path_name request
            [app.save_name,app.path_name] = uiputfile('*.*','Save as: ');
            if isequal(app.save_name,0)
                disp('exit from changeDetection function');
                return
            end
        end

        % Selection changed function: Saveintermediatefiles
        function SaveIntermediateFiles(app, event)
            switch app.Saveintermediatefiles.SelectedObject
                case app.NoButton_save
                    app.opts(5) = 0;
                case app.YesButton_save
                    app.opts(5) = 1;
            end
        end

        % Selection changed function: strictrelaxed
        function StrictRelaxed(app, event)
            switch app.strictrelaxed.SelectedObject
                case app.StrictButton
                    app.opts(3) = 1;
                case app.RelaxedButton
                    app.opts(3) = 2;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangeDetection = uifigure;
            app.ChangeDetection.Name = 'Change Detection for optical images';
            app.RunButton = uibutton(app.ChangeDetection, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.Saveresultsas = uibutton(app.ChangeDetection, 'push');
            app.Saveresultsas.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsAs, true);
            app.Saveresultsas.Text = 'Save results as:';
            app.OptionsPanel = uipanel(app.ChangeDetection);
            app.OptionsPanel.Title = 'Options';
            app.StrategySelection = uibuttongroup(app.OptionsPanel);
            app.StrategySelection.SelectionChangedFcn = createCallbackFcn(app, @Strategy, true);
            app.StrategySelection.Title = 'Select the processing strategy:';
            app.smallData = uiradiobutton(app.StrategySelection);
            app.smallData.Text = 'save in workspace (for small data)';
            app.smallData.Value = true;
            app.BigData = uiradiobutton(app.StrategySelection);
            app.BigData.Text = 'read line by line (for big data)';
            app.Saveintermediatefiles = uibuttongroup(app.OptionsPanel);
            app.Saveintermediatefiles.SelectionChangedFcn = createCallbackFcn(app, @SaveIntermediateFiles, true);
            app.Saveintermediatefiles.Title = 'Do you want to save the intermediate files?';
            app.NoButton_save = uiradiobutton(app.Saveintermediatefiles);
            app.NoButton_save.Text = 'No';
            app.NoButton_save.Value = true;
            app.YesButton_save = uiradiobutton(app.Saveintermediatefiles);
            app.YesButton_save.Text = 'Yes';
            app.EntertheThresholdepslnEditFieldLabel = uilabel(app.OptionsPanel);
            app.EntertheThresholdepslnEditFieldLabel.Text = 'Enter the Threshold epsln:';
            app.EnterThresholdepsln = uieditfield(app.OptionsPanel, 'numeric');
            app.EnterThresholdepsln.Limits = [1e-16 0.05];
            app.EnterThresholdepsln.Value = 0.01;
            app.InitialChangeMaskButton = uibuttongroup(app.OptionsPanel);
            app.InitialChangeMaskButton.SelectionChangedFcn = createCallbackFcn(app, @InitialChangeMask, true);
            app.InitialChangeMaskButton.Title = 'Do you want to use a Initial Change Mask?';
            app.NomaskButton = uiradiobutton(app.InitialChangeMaskButton);
            app.NomaskButton.Text = 'No mask';
            app.NomaskButton.Value = true;
            app.strictrelaxed = uibuttongroup(app.InitialChangeMaskButton);
            app.strictrelaxed.SelectionChangedFcn = createCallbackFcn(app, @StrictRelaxed, true);
            app.strictrelaxed.Visible = 'off';
            app.StrictButton = uiradiobutton(app.strictrelaxed);
            app.StrictButton.Text = 'Strict';
            app.StrictButton.Value = true;
            app.RelaxedButton = uiradiobutton(app.strictrelaxed);
            app.RelaxedButton.Text = 'Relaxed';
            app.UsingPrincipalComponents = uiradiobutton(app.InitialChangeMaskButton);
            app.UsingPrincipalComponents.Text = 'Using Principal Components';
            app.UsingOriginalDataSet = uiradiobutton(app.InitialChangeMaskButton);
            app.UsingOriginalDataSet.Text = 'Using Original DataSet';
            app.ICMvaluemask = uibuttongroup(app.OptionsPanel);
            app.ICMvaluemask.SelectionChangedFcn = createCallbackFcn(app, @ICMvalueMask, true);
            app.ICMvaluemask.Title = 'Mask the lowest values?';
            app.ICMvaluemask.Visible = 'off';
            app.YesButton = uiradiobutton(app.ICMvaluemask);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.ICMvaluemask);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.EnterthepercentegeEditFieldLabel = uilabel(app.ICMvaluemask);
            app.EnterthepercentegeEditFieldLabel.Text = 'Enter the percentege:';
            app.Enterthepercentege = uieditfield(app.ICMvaluemask, 'numeric');
            app.Enterthepercentege.Limits = [0 100];
            app.Enterthepercentege.Visible = 'off';
            app.MADChangeLabel = uilabel(app.ChangeDetection);
            app.MADChangeLabel.Text = 'MADChange';
            app.Panel = uipanel(app.ChangeDetection);
            app.Panel.Title = 'Panel';
            app.InputData = uibutton(app.Panel, 'push');
            app.InputData.ButtonPushedFcn = createCallbackFcn(app, @InputDataPushed, true);
            app.InputData.Text = 'Input Image';
            app.BandNamefortime1EditFieldLabel = uilabel(app.Panel);
            app.BandNamefortime1EditFieldLabel.Text = 'Band Name for time 1';
            app.BandNamefortime1 = uieditfield(app.Panel, 'text');
            app.BandNamefortime1.Value = 'B1,B2,B4,B8';
            app.BandNamefortime2EditFieldLabel = uilabel(app.Panel);
            app.BandNamefortime2EditFieldLabel.Text = 'Band Name for time 2';
            app.BandNamefortime2 = uieditfield(app.Panel, 'text');
            app.BandNamefortime2.Value = 'B1,B2,B4,B8';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangeDetection)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangeDetection)
        end
    end
end