classdef app1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangeDetection              matlab.ui.Figure
        WISHARTChangeLabel           matlab.ui.control.Label
        ReadDataButtonGroup          matlab.ui.container.ButtonGroup
        SelectthedataLabel           matlab.ui.control.Label
        InputButton                  matlab.ui.control.Button
        SaveResultsasButton          matlab.ui.control.Button
        Run                          matlab.ui.control.Button
        VariablesPanel               matlab.ui.container.Panel
        NumberoflooksEditFieldLabel  matlab.ui.control.Label
        NumberoflooksEditField       matlab.ui.control.NumericEditField
        PolarizationButtonGroup      matlab.ui.container.ButtonGroup
        NamesasappearinthedatafileLabel  matlab.ui.control.Label
        HHHH                         matlab.ui.control.EditField
        HHHV                         matlab.ui.control.EditField
        HHVV                         matlab.ui.control.EditField
        HVHV                         matlab.ui.control.EditField
        HVVV                         matlab.ui.control.EditField
        VVVV                         matlab.ui.control.EditField
        PolarizationListBoxLabel     matlab.ui.control.Label
        PolarizationListBox          matlab.ui.control.ListBox
        VVHH                         matlab.ui.control.EditField
        HVHH                         matlab.ui.control.EditField
        VVHV                         matlab.ui.control.EditField
        PvalueEditFieldLabel         matlab.ui.control.Label
        PvalueEditField              matlab.ui.control.NumericEditField
        TimePanel                    matlab.ui.container.Panel
        NumberoftimepointsEditFieldLabel  matlab.ui.control.Label
        NumberoftimepointsEditField  matlab.ui.control.NumericEditField
        NamesTimes                   matlab.ui.container.ButtonGroup
        toLabel                      matlab.ui.control.Label
        MaxRange                     matlab.ui.control.NumericEditField
        MinRange                     matlab.ui.control.NumericEditField
        costumRange                  matlab.ui.control.EditField
        TimeName                     matlab.ui.container.ButtonGroup
        Range                        matlab.ui.control.RadioButton
        Customized                   matlab.ui.control.RadioButton
        RigionofInterestROIPanel     matlab.ui.container.Panel
        ShapefileButton              matlab.ui.control.Button
        Shapefile                    matlab.ui.container.ButtonGroup
        WholeareaButton              matlab.ui.control.RadioButton
        SelectshapefileButton        matlab.ui.control.RadioButton
        SelectinteractivelyButton    matlab.ui.control.RadioButton
        Strategy                     matlab.ui.container.ButtonGroup
        smalldata                    matlab.ui.control.RadioButton
        bigdata                      matlab.ui.control.RadioButton
    end


    properties (Access = public)
        files;
        Nol;
        Pvalue;
        pol;
        pols_name;
        times;
        times_name;
        NoneValue;
        minRange;
        MAXRange;
        HHHHname;
        HHHVname;
        HHVVname;
        HVHVname;
        HVVVname;
        VVVVname;
        CostumRange;
        images;
        info;
        opt;
        strategy;
        wc;
        Prob;
        When;
        ROI;
        shape_file;
        whole;
        file_table;
        file_figure;
        file_Q;
        Prob_ROI;
        file_table_ROI
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = gcp('nocreate');
            if isempty(p)
                parpool;
            end
            app.whole = 1;
            app.minRange = 0;
            app.strategy = 0;
        end

        % Callback function: HHHH, HHHH
        function HHHHValueChanging(app, event)
            app.HHHHname = event.Value;
        end

        % Callback function: HHHV, HHHV
        function HHHVValueChanging(app, event)
            app.HHHVname = event.Value;
        end

        % Callback function: HHVV, HHVV
        function HHVVValueChanging(app, event)
            app.HHVVname = event.Value;
        end

        % Callback function: HVHV, HVHV
        function HVHVValueChanging(app, event)
            app.HVHVname = event.Value;
        end

        % Callback function: HVVV, HVVV
        function HVVVValueChanging(app, event)
            app.HVVVname = event.Value;
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            app.files = uipickfiles; 
            if isempty(app.files)
                error('Exit without selecting any file')
            end
        end

        % Value changed function: PolarizationListBox
        function PolarizationListBoxValueChanged(app, event)
            switch app.PolarizationListBox.Value
                case ''
                    app.HHHH.Enable = 'off';
                    app.HHHV.Enable = 'off';
                    app.HHVV.Enable = 'off';
                    app.HVHV.Enable = 'off';
                    app.HVVV.Enable = 'off';
                    app.VVVV.Enable = 'off';
                    error('Error: choose one of the polarization')
                case 'Full (9)'
                    app.pol = 9;
                    app.HHHH.Enable = 'on';
                    app.HHHV.Enable = 'on';
                    app.HHVV.Enable = 'on';
                    app.HVHV.Enable = 'on';
                    app.HVVV.Enable = 'on';
                    app.VVVV.Enable = 'on';
                case 'Azim (5)'
                    app.pol = 5;
                    app.HHHH.Enable = 'on';
                    app.HHHV.Enable = 'off';
                    app.HHVV.Enable = 'on';
                    app.HVHV.Enable = 'on';
                    app.HVVV.Enable = 'off';
                    app.VVVV.Enable = 'on';
                case 'Dual (4)'
                    app.pol = 4;
                    app.HHHH.Enable = 'on';
                    app.HHHV.Enable = 'on';
                    app.HHVV.Enable = 'off';
                    app.HVHV.Enable = 'on';
                    app.HVVV.Enable = 'off';
                    app.VVVV.Enable = 'off';
                case 'Diagonal (3)'
                    app.pol = 3;
                    app.HHHH.Enable = 'on';
                    app.HHHV.Enable = 'off';
                    app.HHVV.Enable = 'off';
                    app.HVHV.Enable = 'on';
                    app.HVVV.Enable = 'off';
                    app.VVVV.Enable = 'on';
                case 'Dual Diagonal (2)'
                    app.pol = 2;
                    app.HHHH.Enable = 'on';
                    app.HHHV.Enable = 'off';
                    app.HHVV.Enable = 'off';
                    app.HVHV.Enable = 'on';
                    app.HVVV.Enable = 'off';
                    app.VVVV.Enable = 'off';
                case 'Single (1)'
                    app.pol = 1;
                    app.HHHH.Enable = 'on';
                    app.HHHV.Enable = 'off';
                    app.HHVV.Enable = 'off';
                    app.HVHV.Enable = 'off';
                    app.HVVV.Enable = 'off';
                    app.VVVV.Enable = 'off';
            end
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            app.Pvalue = app.PvalueEditField.Value;
            app.times = app.NumberoftimepointsEditField.Value;
            app.Nol = app.NumberoflooksEditField.Value;            
            %main function
            switch app.TimeName.SelectedObject.Text
                case 'Customized'
                    tmp = textscan(app.CostumRange,'%s','Delimiter',',');
                    app.times_name = tmp{1}';
                case 'Range'
                    app.times_name = strseq('',app.minRange:app.MAXRange)';
            end
            switch app.PolarizationListBox.Value
                case ''
                    error('Error: choose one of the polarization')
                case 'Full (9)'
                    app.pols_name = {app.HHHHname,app.HHHVname,app.HHVVname,app.HVHVname,app.HVVVname,app.VVVVname};
                    app.opt = 1;
                case 'Azim (5)'
                    app.pols_name = {app.HHHHname,app.HHVVname,app.HVHVname,app.VVVVname};
                    app.opt = 1;
                case 'Dual (4)'
                    app.pols_name = {app.HHHHname,app.HHHVname,app.HVHVname};
                    app.opt = 1;
                case 'Diagonal (3)'
                    app.pols_name = {app.HHHHname,app.HVHVname,app.VVVVname};
                    %app.opt = 0;
                case 'Dual Diagonal (2)'
                    app.pols_name = {app.HHHHname,app.HVHVname};
                    %app.opt = 0;
                case 'Single (1)'
                    app.pols_name = {app.HHHHname};
                    %app.opt = 0;
            end
            
            WiCHMain(app.files,app.pol,app.pols_name,app.times_name,app.Nol,app.Pvalue,app.times,app.strategy,app.whole,app.file_figure,app.file_table,app.file_Q,app.shape_file)
        end

        % Selection changed function: Strategy
        function StrategySelectionChanged(app, event)
            switch app.Strategy.SelectedObject.Text
                case 'Save in workspace (for small data)'
                    app.strategy = 0;
                case 'Read line by line (for big data)'
                    app.strategy = 1;
            end
        end

        % Callback function: VVVV, VVVV
        function VVVVValueChanging(app, event)
            app.VVVVname = event.Value;
        end

        % Value changing function: costumRange
        function costumRangeValueChanging(app, event)
            app.CostumRange = event.Value;
            
        end

        % Value changed function: MaxRange
        function MaxRangeValueChanged(app, event)
            app.MAXRange = event.Value;
        end

        % Value changed function: MinRange
        function MinRangeValueChanged(app, event)
            app.minRange = event.Value;
        end

        % Button pushed function: SaveResultsasButton
        function SaveResultsasButtonPushed(app, event)
            [save_name,path_name] = uiputfile('*.*','Save as: ');
            
            % set the pathname of the files
            if strcmp(save_name,'') == 1
                app.file_figure          = [path_name,'WishartChanged'];
                app.file_table       = [path_name,'P_Value'];
                app.file_Q       = [path_name,'Q'];
            else
                app.file_figure           = [path_name,'WishartChanged_',save_name];
                app.file_table       = [path_name,'P_Value_',save_name];
                app.file_Q       = [path_name,'Q',save_name];
            end
        end

        % Button pushed function: ShapefileButton
        function ShapefileButtonPushed(app, event)
            [file, path] = uigetfile('*.shp');
            app.shape_file = fullfile(path,file);
        end

        % Selection changed function: TimeName
        function TimeNameSelectionChanged(app, event)
            switch app.TimeName.SelectedObject.Text
                case 'Range'
                    app.costumRange.Enable = 'off';
                    app.MaxRange.Enable = 'on';
                    app.MinRange.Enable = 'on';
                    app.toLabel.Enable = 'on';
                case 'Customized'
                    app.MaxRange.Enable = 'off';
                    app.MinRange.Enable = 'off';
                    app.toLabel.Enable = 'off';
                    app.costumRange.Enable = 'on';
            end 
        end

        % Selection changed function: Shapefile
        function ShapefileSelectionChanged(app, event)
            switch app.Shapefile.SelectedObject.Text
                case 'Whole area'
                    app.ShapefileButton.Visible = 'off';
                    app.shape_file = [];
                    app.whole = 1;
                case 'Select shape file'
                    app.ShapefileButton.Visible = 'on';
                    app.whole = 0;
                case 'Select interactively'
                    app.ShapefileButton.Visible = 'off';
                    app.shape_file = [];
                    app.whole = 0;
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangeDetection = uifigure('Visible', 'off');
            app.ChangeDetection.Name = 'Change Detection for SAR Images';
            app.WISHARTChangeLabel = uilabel(app.ChangeDetection);
            app.WISHARTChangeLabel.Text = 'WISHARTChange';
            app.ReadDataButtonGroup = uibuttongroup(app.ChangeDetection);
            app.ReadDataButtonGroup.Title = 'Read Data:';
            app.SelectthedataLabel = uilabel(app.ReadDataButtonGroup);
            app.SelectthedataLabel.Text = 'Select the data';
            app.InputButton = uibutton(app.ReadDataButtonGroup, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.SaveResultsasButton = uibutton(app.ChangeDetection, 'push');
            app.SaveResultsasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsasButtonPushed, true);
            app.SaveResultsasButton.Text = 'Save Results as:';
            app.Run = uibutton(app.ChangeDetection, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = 'Run';
            app.VariablesPanel = uipanel(app.ChangeDetection);
            app.VariablesPanel.Title = 'Variables:';
            app.NumberoflooksEditFieldLabel = uilabel(app.VariablesPanel);
            app.NumberoflooksEditFieldLabel.Text = 'Number of looks';
            app.NumberoflooksEditField = uieditfield(app.VariablesPanel, 'numeric');
            app.NumberoflooksEditField.Limits = [1 Inf];
            app.NumberoflooksEditField.ValueDisplayFormat = '%11.6g';
            app.NumberoflooksEditField.Value = 1;
            app.PolarizationButtonGroup = uibuttongroup(app.VariablesPanel);
            app.PolarizationButtonGroup.Title = 'Polarization';
            app.NamesasappearinthedatafileLabel = uilabel(app.PolarizationButtonGroup);
            app.NamesasappearinthedatafileLabel.Text = 'Names: (as appear in the data file) ';
            app.HHHH = uieditfield(app.PolarizationButtonGroup, 'text');
            app.HHHH.ValueChangedFcn = createCallbackFcn(app, @HHHHValueChanging, true);
            app.HHHH.ValueChangingFcn = createCallbackFcn(app, @HHHHValueChanging, true);
            app.HHHH.Enable = 'off';
            app.HHHH.Value = 'HHHH';
            app.HHHV = uieditfield(app.PolarizationButtonGroup, 'text');
            app.HHHV.ValueChangedFcn = createCallbackFcn(app, @HHHVValueChanging, true);
            app.HHHV.ValueChangingFcn = createCallbackFcn(app, @HHHVValueChanging, true);
            app.HHHV.Enable = 'off';
            app.HHHV.Value = 'HHHV';
            app.HHVV = uieditfield(app.PolarizationButtonGroup, 'text');
            app.HHVV.ValueChangedFcn = createCallbackFcn(app, @HHVVValueChanging, true);
            app.HHVV.ValueChangingFcn = createCallbackFcn(app, @HHVVValueChanging, true);
            app.HHVV.Enable = 'off';
            app.HHVV.Value = 'HHVV';
            app.HVHV = uieditfield(app.PolarizationButtonGroup, 'text');
            app.HVHV.ValueChangedFcn = createCallbackFcn(app, @HVHVValueChanging, true);
            app.HVHV.ValueChangingFcn = createCallbackFcn(app, @HVHVValueChanging, true);
            app.HVHV.Enable = 'off';
            app.HVHV.Value = 'HVHV';
            app.HVVV = uieditfield(app.PolarizationButtonGroup, 'text');
            app.HVVV.ValueChangedFcn = createCallbackFcn(app, @HVVVValueChanging, true);
            app.HVVV.ValueChangingFcn = createCallbackFcn(app, @HVVVValueChanging, true);
            app.HVVV.Enable = 'off';
            app.HVVV.Value = 'HVVV';
            app.VVVV = uieditfield(app.PolarizationButtonGroup, 'text');
            app.VVVV.ValueChangedFcn = createCallbackFcn(app, @VVVVValueChanging, true);
            app.VVVV.ValueChangingFcn = createCallbackFcn(app, @VVVVValueChanging, true);
            app.VVVV.Enable = 'off';
            app.VVVV.Value = 'VVVV';
            app.PolarizationListBoxLabel = uilabel(app.PolarizationButtonGroup);
            app.PolarizationListBoxLabel.Text = 'Polarization';
            app.PolarizationListBox = uilistbox(app.PolarizationButtonGroup);
            app.PolarizationListBox.Items = {'Full (9)', 'Azim (5)', 'Dual (4)', 'Diagonal (3)', 'Dual Diagonal (2)', 'Single (1)', ''};
            app.PolarizationListBox.ValueChangedFcn = createCallbackFcn(app, @PolarizationListBoxValueChanged, true);
            app.PolarizationListBox.Value = '';
            app.VVHH = uieditfield(app.PolarizationButtonGroup, 'text');
            app.VVHH.Enable = 'off';
            app.VVHH.Value = 'VVHH';
            app.HVHH = uieditfield(app.PolarizationButtonGroup, 'text');
            app.HVHH.Enable = 'off';
            app.HVHH.Value = 'HVHH';
            app.VVHV = uieditfield(app.PolarizationButtonGroup, 'text');
            app.VVHV.Enable = 'off';
            app.VVHV.Value = 'VVHV';
            app.PvalueEditFieldLabel = uilabel(app.VariablesPanel);
            app.PvalueEditFieldLabel.Text = 'P value';
            app.PvalueEditField = uieditfield(app.VariablesPanel, 'numeric');
            app.PvalueEditField.Limits = [0 1];
            app.PvalueEditField.Value = 0.9999;
            app.TimePanel = uipanel(app.VariablesPanel);
            app.TimePanel.Title = 'Time';
            app.NumberoftimepointsEditFieldLabel = uilabel(app.TimePanel);
            app.NumberoftimepointsEditFieldLabel.Text = 'Number of time points';
            app.NumberoftimepointsEditField = uieditfield(app.TimePanel, 'numeric');
            app.NumberoftimepointsEditField.Limits = [2 Inf];
            app.NumberoftimepointsEditField.Value = 2;
            app.NamesTimes = uibuttongroup(app.TimePanel);
            app.NamesTimes.Title = 'Names: (as appear in the data file)';
            app.toLabel = uilabel(app.NamesTimes);
            app.toLabel.Text = 'to';
            app.MaxRange = uieditfield(app.NamesTimes, 'numeric');
            app.MaxRange.Limits = [1 Inf];
            app.MaxRange.ValueDisplayFormat = '%.0f';
            app.MaxRange.ValueChangedFcn = createCallbackFcn(app, @MaxRangeValueChanged, true);
            app.MaxRange.Value = 20;
            app.MinRange = uieditfield(app.NamesTimes, 'numeric');
            app.MinRange.Limits = [0 Inf];
            app.MinRange.ValueDisplayFormat = '%.0f';
            app.MinRange.ValueChangedFcn = createCallbackFcn(app, @MinRangeValueChanged, true);
            app.MinRange.Value = 10;
            app.costumRange = uieditfield(app.NamesTimes, 'text');
            app.costumRange.ValueChangingFcn = createCallbackFcn(app, @costumRangeValueChanging, true);
            app.costumRange.Enable = 'off';
            app.costumRange.Value = '062,063,074';
            app.TimeName = uibuttongroup(app.NamesTimes);
            app.TimeName.SelectionChangedFcn = createCallbackFcn(app, @TimeNameSelectionChanged, true);
            app.Range = uiradiobutton(app.TimeName);
            app.Range.Text = 'Range';
            app.Range.Value = true;
            app.Customized = uiradiobutton(app.TimeName);
            app.Customized.Text = 'Customized';
            app.RigionofInterestROIPanel = uipanel(app.VariablesPanel);
            app.RigionofInterestROIPanel.Title = 'Rigion of Interest (ROI)';
            app.ShapefileButton = uibutton(app.RigionofInterestROIPanel, 'push');
            app.ShapefileButton.ButtonPushedFcn = createCallbackFcn(app, @ShapefileButtonPushed, true);
            app.ShapefileButton.Visible = 'off';
            app.ShapefileButton.Text = 'Shape file';
            app.Shapefile = uibuttongroup(app.RigionofInterestROIPanel);
            app.Shapefile.SelectionChangedFcn = createCallbackFcn(app, @ShapefileSelectionChanged, true);
            app.WholeareaButton = uiradiobutton(app.Shapefile);
            app.WholeareaButton.Text = 'Whole area';
            app.WholeareaButton.Value = true;
            app.SelectshapefileButton = uiradiobutton(app.Shapefile);
            app.SelectshapefileButton.Text = 'Select shape file';
            app.SelectinteractivelyButton = uiradiobutton(app.Shapefile);
            app.SelectinteractivelyButton.Text = 'Select interactively';
            app.Strategy = uibuttongroup(app.ChangeDetection);
            app.Strategy.SelectionChangedFcn = createCallbackFcn(app, @StrategySelectionChanged, true);
            app.Strategy.Title = 'Select the processing strategy:';
            app.smalldata = uiradiobutton(app.Strategy);
            app.smalldata.Text = 'Save in workspace (for small data)';
            app.smalldata.Value = true;
            app.bigdata = uiradiobutton(app.Strategy);
            app.bigdata.Text = 'Read line by line (for big data)';
            % Show the figure after all components are created
            app.ChangeDetection.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangeDetection)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangeDetection)
        end
    end
end